classdef APP2_TTL2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PLAYButton_2                    matlab.ui.control.Button
        IntroducirRuidoButton           matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        AudioRecuperadoLabel            matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        AjustarPotenciadeRuidoLabel     matlab.ui.control.Label
        ModelosdeModulacionButtonGroup  matlab.ui.container.ButtonGroup
        QPSKButton                      matlab.ui.control.RadioButton
        QAMButton                       matlab.ui.control.RadioButton
        MENULabel                       matlab.ui.control.Label
        PLAYButton                      matlab.ui.control.StateButton
        CargarSealButton                matlab.ui.control.Button
        JuanEstebanSarriaMosqueraLabel  matlab.ui.control.Label
        DubanSmithCastroPotosiLabel     matlab.ui.control.Label
        SimuladordeProcesamientodeSealesdeAudioLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarSealButton
        function CargarSealButtonPushed(app, event)
            clc;
            % cuado cargo una nueva señal de audio, se limpian todas las graficas
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            cla(app.UIAxes3,"reset");
            cla(app.UIAxes4,"reset");
            clear sound;
            global signal Fs audioCodificado dict;

            %% carga la señal de audio
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo de audio');
            fullPath = fullfile(path, file);
            [signal, Fs] = audioread(fullPath);

            % Calcula el tiempo correspondiente a cada muestra de la señal
            t_original = (0:length(signal)-1) / Fs;
            % Grafica la señal de audio
            plot(app.UIAxes, t_original, signal);
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            title(app.UIAxes, 'Señal de audio original');

            %% filtro pasabajo con frecuencia de corte de 4 kHz
            fc = 4000; %frecuencia de corte
            N = 100; %orden del filtro
            b = fir1(N, fc/(Fs/2), 'low');
            audioFiltrado = filter(b, 1, signal);
     
            %% Muestrea la señal a 8000 Hz 
            n=5;
            senal_downsampled = downsample(audioFiltrado, n);  
            audioMuestreado=senal_downsampled;

            %% PCM
            N=length(audioMuestreado);
            for i=1:N %valores -2 a 2 acotar 
                if audioMuestreado(i)<-2
                    audioMuestreado(i)=-2;
                end
                if audioMuestreado(i)>2
                    audioMuestreado(i)=1.9999;
                end
            end
            
            x1=audioMuestreado+2; %0-4;
            x255=floor(x1*64); %valores 0-255
            xbin8=de2bi(x255); %valores ([0 0 0 0 0 0 0 0 0] - [1 1 1 1 1 1 1 1])
            xcode=reshape(xbin8',[3,numel(xbin8)/3]); %[0 0 0] y [1 1 1]
            xcode3=xcode'; %
            xc=bi2de(xcode3);%[0 1 2 3 4 5 6 7]
            
            symbols = [0 1 2 3 4 5 6 7];
            p = [0.12 0.1272 0.1280 0.1264 0.1278 0.1259 0.1267 0.1180]; %probabilidad de huffman
            dict = huffmandict(symbols,p);
            audioCodificado = huffmanenco(xc,dict);

            % Calcula el tiempo correspondiente a cada muestra de la señal
            t_codificado = (0:length(audioCodificado)-1) / 8000;
            plot(app.UIAxes2, t_codificado, audioCodificado);
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Valor codificado');
            title(app.UIAxes2, 'Audio Codificado (PCM)');
            app.UIAxes2.YLim = [-0.2 1.2];
        end

        % Selection changed function: ModelosdeModulacionButtonGroup
        function ModelosdeModulacionButtonGroupSelectionChanged(app, event)
                        global audioCodificado audioModulado v1 v2 ef;
                                                            
                        selectedButton = app.ModelosdeModulacionButtonGroup.SelectedObject;
                        v1=app.QAMButton.Value;
                        v2=app.QPSKButton.Value;
                       
                        if v1 == 1
                            % cuado se escoge una nueva modulacion, se limpian todas las graficas
                            cla(app.UIAxes2,"reset");
                            cla(app.UIAxes3,"reset");
                            cla(app.UIAxes4,"reset");

                            disp("16QAM");
                            M=16; N=log2(M); ef=1/4;
                            xcode3=reshape(audioCodificado,N,numel(audioCodificado)/N)';
                            xcoded=bi2de(xcode3); %valores de 0-15
                            audioModulado = qammod(xcoded, M);
                            scatter(app.UIAxes2, real(audioModulado), imag(audioModulado));
                            title(app.UIAxes2, 'Diagrama de dispersión (16QAM) antes del canal');
                            xlabel(app.UIAxes2, 'Parte real');
                            ylabel(app.UIAxes2, 'Parte imaginaria');
                            eyediagram(audioModulado(1:1000), 8)
                            title('Diagrama del ojo (16QAM) antes del canal')

                        elseif v2 == 1
                            % cuado se escoge una nueva modulacion, se limpian todas las graficas
                            cla(app.UIAxes2,"reset");
                            cla(app.UIAxes3,"reset");
                            cla(app.UIAxes4,"reset");

                            disp("QPSK");
                            M=4; N=log2(M); ef=1/2;
                            xcode3=reshape(audioCodificado,N,numel(audioCodificado)/N)';
                            xcoded=bi2de(xcode3); %valores de 0-3
                            audioModulado = pskmod(xcoded, M, pi/4);
                            scatter(app.UIAxes2, real(audioModulado), imag(audioModulado));
                            title(app.UIAxes2, 'Diagrama de dispersión (QPSK) antes del canal');
                            xlabel(app.UIAxes2, 'Parte real');
                            ylabel(app.UIAxes2, 'Parte imaginaria');
                            eyediagram(audioModulado(1:1000), 8)
                            title('Diagrama del ojo (QPSK) antes del canal')
                        
                        end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global value;
            value = app.Slider.Value;
            value = round(value);
            app.EditField.Value = value;
            
        end

        % Button pushed function: IntroducirRuidoButton
        function IntroducirRuidoButtonPushed(app, event)
            global audioModulado v1 v2 value ef audioRecuperado dict;

            %% Canal de comunicaciones AWGN,
            EbNo = 10^(value/10);
            Snr=EbNo*ef; 
            xz=awgn(audioModulado,Snr);
            scatter(app.UIAxes3, real(xz), imag(xz));
            title(app.UIAxes3, 'Diagrama de dispersión después del canal');
            xlabel(app.UIAxes3, 'Parte real');
            ylabel(app.UIAxes3, 'Parte imaginaria');
            eyediagram(xz(1:1000), 8)
            title('Diagrama del ojo después del canal')
            
            %% Demodulacion
            if v1 == 1
                M=16; 
                ym = qamdemod(xz, M);
                ymbi=de2bi(ym); %0-15
                audioDemodulado=reshape(ymbi',numel(ymbi),1);

            elseif v2 == 1
                M=4; 
                ym = pskdemod(xz, M, pi/4);
                ymbi=de2bi(ym); %0-3
                audioDemodulado=reshape(ymbi',numel(ymbi),1);

            end

            %% Decodificacion de fuente
            yc=huffmandeco(audioDemodulado,dict);

            %% Decodificacion PCM
            ycode=de2bi(yc)';
            ybin8=reshape(ycode,8,numel(ycode)/8)';
            yc255=bi2de(ybin8);
            y1=yc255/64;
            audioRecuperado=y1-2;

        end

        % Value changed function: PLAYButton
        function PLAYButtonValueChanged(app, event)
            clear sound;
            global signal Fs;

            boton = app.PLAYButton.Value
            if boton == true
                % Reproduce archivo de audio Original
                sound(signal,Fs);
                app.PLAYButton.Text = "Pause";
            else
                % Pausar archivo de audio
                clear sound;
                app.PLAYButton.Text = "PLAY";
            end
        end

        % Button pushed function: PLAYButton_2
        function PLAYButton_2Pushed(app, event)
            clear sound;
            global audioRecuperado;
        
            if strcmp(app.PLAYButton_2.Text, 'PLAY')
                % Reproduce archivo de audio Recuperada
                sound(audioRecuperado,8000);
                app.PLAYButton_2.Text = "Pause";
                % Calcula el tiempo correspondiente a cada muestra de la señal
                t_recuperado = (0:length(audioRecuperado)-1) / 8000;
                % Grafica la señal de audio
                plot(app.UIAxes4, t_recuperado, audioRecuperado);
                xlabel(app.UIAxes4, 'Tiempo (s)');
                ylabel(app.UIAxes4, 'Amplitud');
                title(app.UIAxes4, 'Señal de audio Recuperada');
            else
                % Pausar archivo de audio
                clear sound;
                app.PLAYButton_2.Text = "PLAY";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SimuladordeProcesamientodeSealesdeAudioLabel = uilabel(app.UIFigure);
            app.SimuladordeProcesamientodeSealesdeAudioLabel.Text = 'Simulador de Procesamiento de Señales de Audio';
            app.DubanSmithCastroPotosiLabel = uilabel(app.UIFigure);
            app.DubanSmithCastroPotosiLabel.Text = 'Duban Smith Castro Potosi';
            app.JuanEstebanSarriaMosqueraLabel = uilabel(app.UIFigure);
            app.JuanEstebanSarriaMosqueraLabel.Text = 'Juan Esteban Sarria Mosquera';
            app.CargarSealButton = uibutton(app.UIFigure, 'push');
            app.CargarSealButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSealButtonPushed, true);
            app.CargarSealButton.Text = 'Cargar Señal';
            app.PLAYButton = uibutton(app.UIFigure, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonValueChanged, true);
            app.PLAYButton.Text = 'PLAY';
            app.MENULabel = uilabel(app.UIFigure);
            app.MENULabel.Text = 'MENU';
            app.ModelosdeModulacionButtonGroup = uibuttongroup(app.UIFigure);
            app.ModelosdeModulacionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelosdeModulacionButtonGroupSelectionChanged, true);
            app.ModelosdeModulacionButtonGroup.Title = 'Modelos de Modulacion';
            app.QAMButton = uiradiobutton(app.ModelosdeModulacionButtonGroup);
            app.QAMButton.Text = '16QAM';
            app.QAMButton.Value = true;
            app.QPSKButton = uiradiobutton(app.ModelosdeModulacionButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.AjustarPotenciadeRuidoLabel = uilabel(app.UIFigure);
            app.AjustarPotenciadeRuidoLabel.Text = 'Ajustar Potencia de Ruido';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.AudioRecuperadoLabel = uilabel(app.UIFigure);
            app.AudioRecuperadoLabel.Text = 'Audio Recuperado';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 50;
            app.IntroducirRuidoButton = uibutton(app.UIFigure, 'push');
            app.IntroducirRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @IntroducirRuidoButtonPushed, true);
            app.IntroducirRuidoButton.Text = 'Introducir Ruido';
            app.PLAYButton_2 = uibutton(app.UIFigure, 'push');
            app.PLAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLAYButton_2Pushed, true);
            app.PLAYButton_2.Text = 'PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP2_TTL2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end