classdef TTL_2_CORTE2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PLAYButton_3                    matlab.ui.control.Button
        SealRecuperadaLabel             matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        AjustarPotenciadeRuidoLabel     matlab.ui.control.Label
        PLAYButton_2                    matlab.ui.control.Button
        ModelosdeModulacionButtonGroup  matlab.ui.container.ButtonGroup
        QPSKButton                      matlab.ui.control.RadioButton
        QAMButton                       matlab.ui.control.RadioButton
        MENULabel                       matlab.ui.control.Label
        PLAYButton                      matlab.ui.control.StateButton
        CargarSealButton                matlab.ui.control.Button
        JuanEstebanSarriaMosqueraLabel  matlab.ui.control.Label
        DubanSmithCastroPotosiLabel     matlab.ui.control.Label
        SimuladordeProcesamientodeSealesdeAudioLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarSealButton
        function CargarSealButtonPushed(app, event)
            clc;
            % cuado cargo una nueva señal de audio, se limpian todas las graficas
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            cla(app.UIAxes3,"reset");
            cla(app.UIAxes4,"reset");
            clear sound;
            global signal Fs audioCodificado;

            %% carga la señal de audio
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo de audio');
            fullPath = fullfile(path, file);
            [signal, Fs] = audioread(fullPath);
            signal = signal(:,1); %canal mono 
            dur = 4*Fs; %duracion solamente de 4 segundos
            signal = signal(1:dur,:); %Duracion acortada de el audio 

            % Calcula el tiempo correspondiente a cada muestra de la señal
            t_original = (0:length(signal)-1) / Fs;
            % Grafica la señal de audio
            plot(app.UIAxes,t_original, signal);
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            title(app.UIAxes, 'Señal de audio original');

            %% filtro pasabajo con frecuencia de corte de 4 kHz
            audioFiltrado = lowpass(signal, 4000, Fs);
     
            %% Muestrea la señal a 8000 Hz utilizando la función resample
            audioMuestreado = resample(audioFiltrado, 8000, Fs);

            %% CUANTIFICACION
            N=256; % niveles de cuantificacion
            [yq,Edges]=discretize(audioMuestreado,N); %discretize es una funcion que cuantifica una señal de entrada en N niveles
            % La función devuelve el vector "yq" que  contiene los valores de cuantificacion a los que pertenece cada muestra  
            % y los bordes de los niveles de cuantificación en el vector "Edges".
            yqr=yq;
            for i=1:N
                yqr(audioMuestreado>=Edges(i)& audioMuestreado<Edges(i+1))=0.5*(Edges(i)+Edges(i+1)); %
            end %  esto tiene el efecto de redondear las muestras cuantificadas a su valor más cercano en el nivel de cuantificación correspondiente.
            
            yqr(audioMuestreado==Edges(N+1))=0.5*(Edges(N)+Edges(N+1));
            % Si una muestra coincide exactamente con el borde superior del último nivel de cuantificación, 
            % se redondea al valor medio de los bordes de los dos últimos niveles de cuantificación.
            
            %% CODIFICACION DE FUENTE
            %Codificacion de Huffman de fuente
            symbols = unique(yqr); % valores cuantizados unicos
            counts = hist(yqr, symbols); % contar la ocurrencia de cada simbolo
            p = counts/sum(counts); % probabilidad de cada simbolo
            dict = huffmandict(symbols, p); % Traductor de codigo huffman
            % codificacion de la señal cuantizada
            audioCodificado = huffmanenco(yqr, dict); % señal codificada
          
            % Calcula el tiempo correspondiente a cada muestra de la señal
            t_codificado = (0:length(audioCodificado)-1) / 8000;
            plot(app.UIAxes2, t_codificado, audioCodificado);
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Valor codificado');
            title(app.UIAxes2, 'Audio Codificado (PCM)');
            app.UIAxes2.YLim = [-0.2 1.2];
        end

        % Value changed function: PLAYButton
        function PLAYButtonValueChanged(app, event)
                        clear sound;
                        global signal Fs;
            
                        value = app.PLAYButton.Value
                        if value == true
                            % Reproduce archivo de audio Original
                            sound(signal,Fs);
                            app.PLAYButton.Text = "Pause";
                        else
                            % Pausar archivo de audio
                            clear sound;
                            app.PLAYButton.Text = "Play";
                        end
        end

        % Selection changed function: ModelosdeModulacionButtonGroup
        function ModelosdeModulacionButtonGroupSelectionChanged(app, event)
                        global audioCodificado;
                                                            
                        selectedButton = app.ModelosdeModulacionButtonGroup.SelectedObject;
                        v1=app.QAMButton.Value;
                        v2=app.QPSKButton.Value;
                        
                        xh=audioCodificado;
                        
                        if v1 == 1
                            % cuado se escoge una nueva modulacion, se limpian todas las graficas
%                             cla(app.UIAxes,"reset");
%                             cla(app.UIAxes2,"reset");
                            cla(app.UIAxes3,"reset");
                            cla(app.UIAxes4,"reset");

                            disp("16QAM");
                            M=16; N=log2(M); ef=1/4;
                            xh = randi([0 1],1000,1); % Genera una secuencia binaria aleatoria
                            xcode3=reshape(xh,N,numel(xh)/N)';
                            xcoded=bi2de(xcode3); %valores de 0-15
                            xm = qammod(xcoded, M);
                            scatterplot(xm,'Parent',app.UIAxes3);
                            title(app.UIAxes3,'Diagrama de dispersión (16QAM) antes del canal');
                            xlabel(app.UIAxes3,'Parte real');
                            ylabel(app.UIAxes3,'Parte imaginaria');
                            eyediagram(xm(1:1000), 8,'Parent',app.UIAxes4);
                            title(app.UIAxes4,'Diagrama del ojo (16QAM) antes del canal');

                        elseif v2 == 1
                            % cuado se escoge una nueva modulacion, se limpian todas las graficas
%                             cla(app.UIAxes,"reset");
%                             cla(app.UIAxes2,"reset");
                            cla(app.UIAxes3,"reset");
                            cla(app.UIAxes4,"reset");
                            disp("QPSK");
                            

                        end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SimuladordeProcesamientodeSealesdeAudioLabel = uilabel(app.UIFigure);
            app.SimuladordeProcesamientodeSealesdeAudioLabel.Text = 'Simulador de Procesamiento de Señales de Audio';
            app.DubanSmithCastroPotosiLabel = uilabel(app.UIFigure);
            app.DubanSmithCastroPotosiLabel.Text = 'Duban Smith Castro Potosi';
            app.JuanEstebanSarriaMosqueraLabel = uilabel(app.UIFigure);
            app.JuanEstebanSarriaMosqueraLabel.Text = 'Juan Esteban Sarria Mosquera';
            app.CargarSealButton = uibutton(app.UIFigure, 'push');
            app.CargarSealButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSealButtonPushed, true);
            app.CargarSealButton.Text = 'Cargar Señal';
            app.PLAYButton = uibutton(app.UIFigure, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonValueChanged, true);
            app.PLAYButton.Text = 'PLAY';
            app.MENULabel = uilabel(app.UIFigure);
            app.MENULabel.Text = 'MENU';
            app.ModelosdeModulacionButtonGroup = uibuttongroup(app.UIFigure);
            app.ModelosdeModulacionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelosdeModulacionButtonGroupSelectionChanged, true);
            app.ModelosdeModulacionButtonGroup.Title = 'Modelos de Modulacion';
            app.QAMButton = uiradiobutton(app.ModelosdeModulacionButtonGroup);
            app.QAMButton.Text = '16QAM';
            app.QAMButton.Value = true;
            app.QPSKButton = uiradiobutton(app.ModelosdeModulacionButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.PLAYButton_2 = uibutton(app.UIFigure, 'push');
            app.PLAYButton_2.Text = 'PLAY';
            app.AjustarPotenciadeRuidoLabel = uilabel(app.UIFigure);
            app.AjustarPotenciadeRuidoLabel.Text = 'Ajustar Potencia de Ruido';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Value = 50;
            app.SealRecuperadaLabel = uilabel(app.UIFigure);
            app.SealRecuperadaLabel.Text = 'Señal Recuperada';
            app.PLAYButton_3 = uibutton(app.UIFigure, 'push');
            app.PLAYButton_3.Text = 'PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTL_2_CORTE2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end